classdef Gane_Final_Version < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Scene4         matlab.ui.control.Image
        Fish           matlab.ui.control.Image
        Background     matlab.ui.control.Image
        Scene3         matlab.ui.control.Image
        Scene2         matlab.ui.control.Image
        Scene1         matlab.ui.control.Image
        Slide1         matlab.ui.control.Image
        Ending4        matlab.ui.control.Image
        Ending3        matlab.ui.control.Image
        Ending2And1    matlab.ui.control.Image
        Ending0        matlab.ui.control.Image
        Slide9         matlab.ui.control.Image
        Slide8         matlab.ui.control.Image
        Slide7         matlab.ui.control.Image
        Slide6         matlab.ui.control.Image
        Slide5         matlab.ui.control.Image
        Slide4         matlab.ui.control.Image
        Slide3         matlab.ui.control.Image
        Slide2         matlab.ui.control.Image
        QuestionLabel  matlab.ui.control.Label
        ChoiceLabel    matlab.ui.control.Label
        StartButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        ypos=250;
        xpos=715;
        xmoveamount=5;
        ymoveamount=15;
        storedxpos;
        storedypos;
        score=4;
        status=0;
        remainquestion=4;
        slidestatus;
        gamestatus=0;
        ending=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           if app.gamestatus==0
           app.Slide1.Visible="on";
           app.QuestionLabel.Visible="off";
           app.ChoiceLabel.Visible="off";
           app.Background.Visible="off";
           app.Scene1.Visible="off";
           app.Scene2.Visible="off";
           app.Scene3.Visible="off";
           app.Scene4.Visible="off";
           app.Ending0.Visible="off";
           app.Ending2And1.Visible="off";
           app.Ending3.Visible="off";
           app.Ending4.Visible="off";
           app.Fish.Visible="off";
           app.Slide2.Visible="off";
           app.Slide3.Visible="off";
           app.Slide4.Visible="off";
           app.Slide5.Visible="off";
           app.Slide6.Visible="off";
           app.Slide7.Visible="off";
           app.Slide8.Visible="off";
           app.Slide9.Visible="off";
           app.xpos=250;
           app.ypos=715;
           app.remainquestion=4;
           app.score=4;
           app.Fish.Position=[250 715 100 100];
           app.gamestatus=0;
           app.slidestatus=9; 
           app.ending=0;
           end
           if app.gamestatus==1
           app.Slide1.Visible="off";
           app.QuestionLabel.Visible="off";
           app.ChoiceLabel.Visible="off";
           app.Background.Visible="on";
           app.Scene1.Visible="off";
           app.Scene2.Visible="off";
           app.Scene3.Visible="off";
           app.Scene4.Visible="off";
           app.Ending0.Visible="off";
           app.Ending2And1.Visible="off";
           app.Ending3.Visible="off";
           app.Ending4.Visible="off";
           app.Fish.Visible="on";
           app.Slide2.Visible="off";
           app.Slide3.Visible="off";
           app.Slide4.Visible="off";
           app.Slide5.Visible="off";
           app.Slide6.Visible="off";
           app.Slide7.Visible="off";
           app.Slide8.Visible="off";
           app.Slide9.Visible="off";
           app.xpos=250;
           app.ypos=715;
           app.remainquestion=4;
           app.score=4;
           app.Fish.Position=[250 715 100 100];
           app.gamestatus=1;
           app.slidestatus=9;
           app.ending=0;
           end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
     
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            if app.gamestatus==0
                if double(key)==98
                    app.StartButtonPushed;
                end
            if app.Slide1.Visible=="on"&&double(key)==120&&app.slidestatus==9
                app.Slide1.Visible="off";
                app.Slide2.Visible="on";
            end
            if app.Slide2.Visible=="on"&&double(key)==120&&app.slidestatus==8
                app.Slide2.Visible="off";
                app.Slide3.Visible="on";
            end
            if app.Slide3.Visible=="on"&&double(key)==120&&app.slidestatus==7
                app.Slide3.Visible="off";
                app.Slide4.Visible="on";
            end
            if app.Slide4.Visible=="on"&&double(key)==120&&app.slidestatus==6
                app.Slide4.Visible="off";
                app.Slide5.Visible="on";
            end
            if app.Slide5.Visible=="on"&&double(key)==120&&app.slidestatus==5
                app.Slide5.Visible="off";
                app.Slide6.Visible="on";
            end
            if app.Slide6.Visible=="on"&&double(key)==120&&app.slidestatus==4
                app.Slide6.Visible="off";
                app.Slide7.Visible="on";
            end
            if app.Slide7.Visible=="on"&&double(key)==120&&app.slidestatus==3
                app.Slide7.Visible="off";
                app.Slide8.Visible="on";
            end
            if app.Slide8.Visible=="on"&&double(key)==120&&app.slidestatus==2
                app.Slide8.Visible="off";
                app.Slide9.Visible="on";
            end
            if app.Slide9.Visible=="on"&&double(key)==120&&app.slidestatus==1
                app.Slide9.Visible="on";
            end
            if app.Slide9.Visible=="on"&&double(key)==120&&app.slidestatus==0
                app.Slide9.Visible="off";
                app.Fish.Visible="on";
                app.Background.Visible="on";
                app.gamestatus=1;
            end


            if app.Slide9.Visible=="on"&&double(key)==122&&app.slidestatus==0
                app.Slide9.Visible="off";
                app.Slide8.Visible="on";
            end
            if app.Slide8.Visible=="on"&&double(key)==122&&app.slidestatus==2
                app.Slide8.Visible="off";
                app.Slide7.Visible="on";
            end
            if app.Slide7.Visible=="on"&&double(key)==122&&app.slidestatus==3
                app.Slide7.Visible="off";
                app.Slide6.Visible="on";
            end
            if app.Slide6.Visible=="on"&&double(key)==122&&app.slidestatus==4
                app.Slide6.Visible="off";
                app.Slide5.Visible="on";
            end
            if app.Slide5.Visible=="on"&&double(key)==122&&app.slidestatus==5
                app.Slide5.Visible="off";
                app.Slide4.Visible="on";
            end
            if app.Slide4.Visible=="on"&&double(key)==122&&app.slidestatus==6
                app.Slide4.Visible="off";
                app.Slide3.Visible="on";
            end
            if app.Slide3.Visible=="on"&&double(key)==122&&app.slidestatus==7
                app.Slide3.Visible="off";
                app.Slide2.Visible="on";
            end
            if app.Slide2.Visible=="on"&&double(key)==122&&app.slidestatus==8
                app.Slide2.Visible="off";
                app.Slide1.Visible="on";
            end
            end
            
           
           % Game
           if app.gamestatus==1
           if double(key)==119
           app.storedypos=app.ypos;
           app.ypos=app.ypos+app.ymoveamount;
                app.Fish.Position=[app.xpos app.ypos 100 100];
           end
           if double(key)==115
           app.ypos=app.ypos-app.ymoveamount;
                app.Fish.Position=[app.xpos app.ypos 100 100];
           end
           if double(key)==100
           app.storedxpos=app.xpos;
           app.xpos=app.xpos+app.xmoveamount;
                app.Fish.Position=[app.xpos app.ypos 100 100];
           end
           if double(key)==97
           app.storedxpos=app.xpos;
           app.xpos=app.xpos-app.xmoveamount;
                app.Fish.Position=[app.xpos app.ypos 100 100];
           end
           if double(key)==118&&app.status==0
             app.gamestatus=1;
            app.StartButtonPushed;
           end
            if double(key)==98&&app.status==0
             app.gamestatus=0;
            app.StartButtonPushed;
           end
           if app.ypos<383&&app.xpos>389&&app.ypos>260&&app.Background.Visible=="on"
               app.Background.Visible="off";
               app.Scene1.Visible="on";
               if app.ypos>=315
               app.xpos=125;
               app.ypos=835;
               end
               if app.ypos<315
               app.xpos=117;
               app.ypos=91;
               end
               app.Fish.Position=[app.xpos app.ypos 100 100];
           end
           if app.xpos>1229&&app.Scene1.Visible=="on"
               app.Scene1.Visible="off";
               app.Scene2.Visible="on";
               app.xpos=74;
               app.ypos=354;
               app.Fish.Position=[app.xpos app.ypos 100 100];
           end
           if app.xpos>1216&&app.Scene2.Visible=="on"
               app.Scene2.Visible="off";
               app.Scene3.Visible="on";
               app.xpos=71;
               app.ypos=357;
               app.Fish.Position=[app.xpos app.ypos 100 100];
           end
           if app.xpos>413&&app.Scene3.Visible=="on"
               app.Fish.Visible="off";
             if app.score==4
               for i=1:10
               app.Scene3.Visible="off";
               app.Scene4.Visible="on";
               pause(0.2);
               app.Scene4.Visible="off";
               app.Scene3.Visible="on";
               pause(0.2);
               end
               app.Scene4.Visible="off";
               app.Scene3.Visible="off";
               app.ending=1;
             elseif app.score==3
               for i=1:7
               app.Scene3.Visible="off";
               app.Scene4.Visible="on";
               pause(0.4);
               app.Scene4.Visible="off";
               app.Scene3.Visible="on";
               pause(0.4);
               end
                app.Scene4.Visible="off";
                app.Scene3.Visible="off";
                app.ending=1;
             elseif app.score==2
               for i=1:4
               app.Scene3.Visible="off";
               app.Scene4.Visible="on";
               pause(0.6);
               app.Scene4.Visible="off";
               app.Scene3.Visible="on";
               pause(0.6);
               end
                app.Scene4.Visible="off";
                app.Scene3.Visible="off";
                app.ending=1;
             elseif app.score==1
               for i=1:4
               app.Scene3.Visible="off";
               app.Scene4.Visible="on";
               pause(0.6);
               app.Scene4.Visible="off";
               app.Scene3.Visible="on";
               pause(0.6);
               end
                app.Scene4.Visible="off";
                app.Scene3.Visible="off";
                app.ending=1;
             elseif app.score==0
                   app.Scene3.Visible="off";
                   app.Scene4.Visible="off";
                   app.ending=1;
             end
           end
           if app.Scene3.Visible=="off"&&app.Scene4.Visible=="off"&&app.ending==1
                 if app.score==4
                     app.Ending4.Visible="on";
                     app.gamestatus=0;
                     app.ending=0;
                 end
                 if app.score==3
                     app.Ending3.Visible="on";
                     app.gamestatus=0;
                     app.ending=0;
                 end
                 if app.score==2||app.score==1
                     app.Ending2And1.Visible="on";
                     app.gamestatus=0;
                     app.ending=0;
                 end
                 if app.score==0
                     app.Ending0.Visible="on";
                     app.gamestatus=0;
                     app.ending=0;
                 end
           end
           % Background Boundary
           if app.xpos>445&&app.ypos>=386&&app.Background.Visible=="on"
               app.xpos=app.xpos-app.xmoveamount;
               app.ypos=app.ypos;
               app.Fish.Position=[app.xpos app.ypos 100 100];
           end
           if app.ypos>=736&&app.Background.Visible=="on"
               app.xpos=app.xpos;
               app.ypos=app.ypos-app.ymoveamount;
               app.Fish.Position=[app.xpos app.ypos 100 100];
           end
           if app.xpos<66&&app.Background.Visible=="on"
               app.xpos=app.xpos+app.xmoveamount;
               app.ypos=app.ypos;
               app.Fish.Position=[app.xpos app.ypos 100 100];
           end
            if app.ypos<136&&app.Background.Visible=="on"
               app.xpos=app.xpos;
               app.ypos=app.ypos+app.ymoveamount;
               app.Fish.Position=[app.xpos app.ypos 100 100];
            end
            if app.ypos<=237&&app.xpos>385&&app.Background.Visible=="on"
               app.xpos=app.xpos-app.xmoveamount;
               app.ypos=app.ypos;
               app.Fish.Position=[app.xpos app.ypos 100 100];
            end

            %Scene 1 Boundary
            if app.xpos>=-(app.ypos-7034.7)/24.667&&app.xpos<364&&app.ypos>498&&app.Scene1.Visible=="on"
               app.xpos=app.xpos-app.xmoveamount;
               app.ypos=app.ypos;
               app.Fish.Position=[app.xpos app.ypos 100 100];
            end
            if app.xpos>=(app.ypos+451.01)/2.3868&&app.xpos<=300&&app.ypos<247&&app.Scene1.Visible=="on"
               app.xpos=app.xpos-app.xmoveamount;
               app.ypos=app.ypos;
               app.Fish.Position=[app.xpos app.ypos 100 100];
            end
            if app.xpos>269&&app.ypos>465&&app.Scene1.Visible=="on"
               app.xpos=app.xpos;
               app.ypos=app.ypos-app.ymoveamount;
               app.Fish.Position=[app.xpos app.ypos 100 100];
            end
            if app.xpos>294&&app.ypos<260&&app.Scene1.Visible=="on"
               app.xpos=app.xpos;
               app.ypos=app.ypos+app.ymoveamount;
               app.Fish.Position=[app.xpos app.ypos 100 100];
            end

            if app.ypos>869&&app.Scene1.Visible=="on"
               app.xpos=app.xpos;
               app.ypos=app.ypos-app.ymoveamount;
               app.Fish.Position=[app.xpos app.ypos 100 100];
            end
            if app.ypos<=-27&&app.Scene1.Visible=="on"
               app.xpos=app.xpos;
               app.ypos=app.ypos+app.ymoveamount;
               app.Fish.Position=[app.xpos app.ypos 100 100];
            end
            if app.xpos<53&&app.Scene1.Visible=="on"
               app.xpos=app.xpos+app.xmoveamount;
               app.ypos=app.ypos;
               app.Fish.Position=[app.xpos app.ypos 100 100];
            end

             % Scene 2 Boundary
             if app.xpos<58&&app.Scene2.Visible=="on"
               app.xpos=app.xpos+app.xmoveamount;
               app.ypos=app.ypos;
               app.Fish.Position=[app.xpos app.ypos 100 100];
             end
             if app.ypos>456&&app.Scene2.Visible=="on"
               app.xpos=app.xpos;
               app.ypos=app.ypos-app.ymoveamount;
               app.Fish.Position=[app.xpos app.ypos 100 100];
             end
             if app.ypos<256&&app.Scene2.Visible=="on"
               app.xpos=app.xpos;
               app.ypos=app.ypos+app.ymoveamount;
               app.Fish.Position=[app.xpos app.ypos 100 100];
             end

             % Scene 3 Boundary
             if app.xpos<56&&app.Scene3.Visible=="on"
               app.xpos=app.xpos+app.xmoveamount;
               app.ypos=app.ypos;
               app.Fish.Position=[app.xpos app.ypos 100 100];
             end
             if app.ypos>478&&app.Scene3.Visible=="on"
               app.xpos=app.xpos;
               app.ypos=app.ypos-app.ymoveamount;
               app.Fish.Position=[app.xpos app.ypos 100 100];
             end
             if app.ypos<265&&app.Scene3.Visible=="on"
               app.xpos=app.xpos;
               app.ypos=app.ypos+app.ymoveamount;
               app.Fish.Position=[app.xpos app.ypos 100 100];
             end

             % Question at Background
            if app.ypos<475&&app.Background.Visible=="on"&&app.remainquestion==4&&app.QuestionLabel.Visible=="off"
                app.remainquestion=app.remainquestion-1;
                app.status=1;
                app.Background.Visible="off";
                app.storedxpos=app.xpos;
                app.storedypos=app.ypos;
                app.Fish.Visible="off";
                app.QuestionLabel.Visible="on";
                app.ChoiceLabel.Visible="on";
            end
            if app.remainquestion==3&&app.QuestionLabel.Visible=="on"
                app.QuestionLabel.Text="What is one of the solutions humans developed to allow salmon to coexist with hydroelectric plants?"; 
                app.ChoiceLabel.Text="A. Salmon trap door B. Salmon machine gun C. Salmon water elevator D. Salmon cannon";
                app.QuestionLabel.WordWrap="on";
            end
            if double(key)==98 &&app.QuestionLabel.Visible=="on"&&app.remainquestion==3
                   app.QuestionLabel.Text='Correct!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Background.Visible="on";
            end
            if app.QuestionLabel.Visible=="on"&&app.remainquestion==3&&double(key)==120 
                   app.QuestionLabel.Text='Wrong!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.score=app.score-1;
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Background.Visible="on";
            end
             if app.QuestionLabel.Visible=="on"&&app.remainquestion==3&&double(key)==118
                   app.QuestionLabel.Text='Wrong!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.score=app.score-1;
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Background.Visible="on";
            end
             if app.QuestionLabel.Visible=="on"&&app.remainquestion==3&&double(key)==122
                   app.QuestionLabel.Text='Wrong!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.score=app.score-1;
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Background.Visible="on";
            end
            % Question at Scene 1
            if app.xpos>728&&app.Scene1.Visible=="on"&&app.remainquestion==3
                app.remainquestion=app.remainquestion-1;
                app.status=1;
                app.Scene1.Visible="off";
                app.storedxpos=app.xpos;
                app.storedypos=app.ypos;
                app.Fish.Visible="off";
                app.QuestionLabel.Visible="on";
                app.ChoiceLabel.Visible="on";
            end
            if app.remainquestion==2&&app.QuestionLabel.Visible=="on"
                app.QuestionLabel.Text="What is the name for the structures that change the courses of river flow?"; 
                app.ChoiceLabel.Text="A. Reroute system     B. Diversion facility   C. Flow center  D. Deflection point";
            end
            if double(key)==120 &&app.QuestionLabel.Visible=="on"&&app.remainquestion==2
                   app.QuestionLabel.Text='Correct!';
                   pause(1);
                   app.QuestionLabel.Visible="off";
                   app.ChoiceLabel.Visible="off";
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Scene1.Visible="on";
            end
            if app.QuestionLabel.Visible=="on"&&app.remainquestion==2&&double(key)==122
                   app.QuestionLabel.Text='Wrong!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.score=app.score-1;
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Scene1.Visible="on";
            end
            if app.QuestionLabel.Visible=="on"&&app.remainquestion==2&&double(key)==118
                   app.QuestionLabel.Text='Wrong!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.score=app.score-1;
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Scene1.Visible="on";
            end
            if app.QuestionLabel.Visible=="on"&&app.remainquestion==2&&double(key)==98
                   app.QuestionLabel.Text='Wrong!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.score=app.score-1;
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Scene1.Visible="on";
            end
            % Question at Scene 2
            if app.xpos>800&&app.Scene2.Visible=="on"&&app.remainquestion==2&&app.QuestionLabel.Visible=="off"
                app.remainquestion=app.remainquestion-1;
                app.status=1;
                app.storedxpos=app.xpos;
                app.storedypos=app.ypos;
                app.Scene2.Visible="off";
                app.Fish.Visible="off";
                app.QuestionLabel.Visible="on";
                app.ChoiceLabel.Visible="on";
            end
            if app.remainquestion==1&&app.QuestionLabel.Visible=="on"
                app.QuestionLabel.Text="How much of the US s renewable electricity generation is attributed to hydroelectricity?";
                app.ChoiceLabel.Text="A. 15.3% B. 46.8% C. 31.5% D. 25.5% ";
            end
            if double(key)==118 &&app.QuestionLabel.Visible=="on"&&app.remainquestion==1
                   app.QuestionLabel.Text='Correct!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Scene2.Visible="on";
            end
            if app.QuestionLabel.Visible=="on"&&app.remainquestion==1&&double(key)==122
                   app.QuestionLabel.Text='Wrong!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.score=app.score-1;
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Scene2.Visible="on";
            end
             if app.QuestionLabel.Visible=="on"&&app.remainquestion==1&&double(key)==120
                   app.QuestionLabel.Text='Wrong!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.score=app.score-1;
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Scene2.Visible="on";
             end
             if app.QuestionLabel.Visible=="on"&&app.remainquestion==1&&double(key)==98
                   app.QuestionLabel.Text='Wrong!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.score=app.score-1;
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Scene2.Visible="on";
            end
            % Question at Scene 3
            if app.xpos>189&&app.Scene3.Visible=="on"&&app.remainquestion==1&&app.QuestionLabel.Visible=="off"
                app.remainquestion=app.remainquestion-1;
                app.status=1;
                app.Scene3.Visible="off";
                app.Fish.Visible="off"; 
                app.storedxpos=app.xpos;
                app.storedypos=app.ypos;
                app.QuestionLabel.Visible="on";
                app.ChoiceLabel.Visible="on";
            end
            if app.remainquestion==0&&app.QuestionLabel.Visible=="on"
                app.QuestionLabel.Text="How do the dams generate electricity?";
                app.ChoiceLabel.Text="A. Windmills B. Photosynthesis C. Solar energy D. Turbines";
            end
            if double(key)==98 &&app.QuestionLabel.Visible=="on"&&app.remainquestion==0
                   app.QuestionLabel.Text='Correct!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Scene3.Visible="on";
            end
            if app.QuestionLabel.Visible=="on"&&app.remainquestion==0&&double(key)==122
                   app.QuestionLabel.Text='Wrong!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.score=app.score-1;
                   app.status=0; 
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Scene3.Visible="on";
            end  
             if app.QuestionLabel.Visible=="on"&&app.remainquestion==0&&double(key)==118
                   app.QuestionLabel.Text='Wrong!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.score=app.score-1;
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Scene3.Visible="on";
             end
              if app.QuestionLabel.Visible=="on"&&app.remainquestion==0&&double(key)==120
                   app.QuestionLabel.Text='Wrong!';
                   pause(1);
                   app.QuestionLabel.Visible='off';
                   app.ChoiceLabel.Visible="off";
                   app.score=app.score-1;
                   app.status=0;
                   app.xpos=app.storedxpos;
                   app.ypos=app.storedypos;
                   app.Fish.Position=[app.xpos app.ypos 100 100];
                   app.Fish.Visible="on";
                   app.Scene3.Visible="on";
              end
           end
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;
            if app.gamestatus==0
            if app.Slide2.Visible=="on"&&double(key)==120&&app.slidestatus==9
                app.Slide1.Visible="off";
                app.Slide2.Visible="on";
                app.slidestatus=app.slidestatus-1;
            end
            if app.Slide3.Visible=="on"&&double(key)==120&&app.slidestatus==8
                app.Slide2.Visible="off";
                app.Slide3.Visible="on";
                app.slidestatus=app.slidestatus-1;
            end
             if app.Slide4.Visible=="on"&&double(key)==120&&app.slidestatus==7
                app.Slide3.Visible="off";
                app.Slide4.Visible="on";
                app.slidestatus=app.slidestatus-1;
             end
             if app.Slide5.Visible=="on"&&double(key)==120&&app.slidestatus==6
                app.Slide4.Visible="off";
                app.Slide5.Visible="on";
                app.slidestatus=app.slidestatus-1;
             end
             if app.Slide6.Visible=="on"&&double(key)==120&&app.slidestatus==5
                app.Slide5.Visible="off";
                app.Slide6.Visible="on";
                app.slidestatus=app.slidestatus-1;
             end
             if app.Slide7.Visible=="on"&&double(key)==120&&app.slidestatus==4
                app.Slide6.Visible="off";
                app.Slide7.Visible="on";
                app.slidestatus=app.slidestatus-1;
             end
             if app.Slide8.Visible=="on"&&double(key)==120&&app.slidestatus==3
                app.Slide7.Visible="off";
                app.Slide8.Visible="on";
                app.slidestatus=app.slidestatus-1;
             end
             if app.Slide9.Visible=="on"&&double(key)==120&&app.slidestatus==2
                app.Slide8.Visible="off";
                app.Slide9.Visible="on";
                app.slidestatus=app.slidestatus-1;
             end
             if app.Slide9.Visible=="on"&&double(key)==120&&app.slidestatus==1
                app.Slide9.Visible="on";
                app.slidestatus=app.slidestatus-1;
             end

             if app.Slide8.Visible=="on"&&double(key)==122&&app.slidestatus==0
                app.Slide9.Visible="off";
                app.Slide8.Visible="on";
                app.slidestatus=app.slidestatus+2;
             end
            if app.Slide7.Visible=="on"&&double(key)==122&&app.slidestatus==2
                app.slidestatus=app.slidestatus+1;
            end
            if app.Slide6.Visible=="on"&&double(key)==122&&app.slidestatus==3
                app.slidestatus=app.slidestatus+1;
            end
            if app.Slide5.Visible=="on"&&double(key)==122&&app.slidestatus==4
                app.slidestatus=app.slidestatus+1;
            end
            if app.Slide4.Visible=="on"&&double(key)==122&&app.slidestatus==5
                app.slidestatus=app.slidestatus+1;
            end
            if app.Slide3.Visible=="on"&&double(key)==122&&app.slidestatus==6
                app.slidestatus=app.slidestatus+1;
            end
            if app.Slide2.Visible=="on"&&double(key)==122&&app.slidestatus==7
                app.slidestatus=app.slidestatus+1;
            end
            if app.Slide1.Visible=="on"&&double(key)==122&&app.slidestatus==8
                app.slidestatus=app.slidestatus+1;
             end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ChoiceLabel = uilabel(app.UIFigure);
            app.QuestionLabel = uilabel(app.UIFigure);
            app.QuestionLabel.Text = '';
            app.Slide2 = uiimage(app.UIFigure);
            app.Slide2.ImageSource = fullfile(pathToMLAPP, 'Slide2.jpg');
            app.Slide3 = uiimage(app.UIFigure);
            app.Slide3.ImageSource = fullfile(pathToMLAPP, 'Slide3.jpg');
            app.Slide4 = uiimage(app.UIFigure);
            app.Slide4.ImageSource = fullfile(pathToMLAPP, 'Slide4.jpg');
            app.Slide5 = uiimage(app.UIFigure);
            app.Slide5.ImageSource = fullfile(pathToMLAPP, 'Slide5.jpg');
            app.Slide6 = uiimage(app.UIFigure);
            app.Slide6.ImageSource = fullfile(pathToMLAPP, 'Slide6.jpg');
            app.Slide7 = uiimage(app.UIFigure);
            app.Slide7.ImageSource = fullfile(pathToMLAPP, 'Slide7.jpg');
            app.Slide8 = uiimage(app.UIFigure);
            app.Slide8.ImageSource = fullfile(pathToMLAPP, 'Slide8.jpg');
            app.Slide9 = uiimage(app.UIFigure);
            app.Slide9.ImageSource = fullfile(pathToMLAPP, 'Slide9.jpg');
            app.Ending0 = uiimage(app.UIFigure);
            app.Ending0.ImageSource = fullfile(pathToMLAPP, 'IMG_0627.jpg');
            app.Ending2And1 = uiimage(app.UIFigure);
            app.Ending2And1.ImageSource = fullfile(pathToMLAPP, 'IMG_0626.jpg');
            app.Ending3 = uiimage(app.UIFigure);
            app.Ending3.ImageSource = fullfile(pathToMLAPP, 'IMG_0625.jpg');
            app.Ending4 = uiimage(app.UIFigure);
            app.Ending4.ImageSource = fullfile(pathToMLAPP, 'IMG_0624.jpg');
            app.Slide1 = uiimage(app.UIFigure);
            app.Slide1.ImageSource = fullfile(pathToMLAPP, 'Slide1.jpg');
            app.Scene1 = uiimage(app.UIFigure);
            app.Scene1.ImageSource = fullfile(pathToMLAPP, 'IMG_0622.jpg');
            app.Scene2 = uiimage(app.UIFigure);
            app.Scene2.ImageSource = fullfile(pathToMLAPP, 'IMG_0623.jpg');
            app.Scene3 = uiimage(app.UIFigure);
            app.Scene3.ImageSource = fullfile(pathToMLAPP, 'IMG_0620.jpg');
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = fullfile(pathToMLAPP, 'IMG_0628.jpg');
            app.Fish = uiimage(app.UIFigure);
            app.Fish.ImageSource = fullfile(pathToMLAPP, 'IMG-0618.PNG');
            app.Scene4 = uiimage(app.UIFigure);
            app.Scene4.ImageSource = fullfile(pathToMLAPP, 'IMG_0621.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gane_Final_Version

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end